classdef Encuesta2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button_3                       matlab.ui.control.Button
        NmerotelefnicoEditField        matlab.ui.control.EditField
        NmerotelefnicoEditFieldLabel   matlab.ui.control.Label
        EstadoCivilEditField           matlab.ui.control.EditField
        EstadoCivilEditFieldLabel      matlab.ui.control.Label
        FechadenacimientoDatePicker    matlab.ui.control.DatePicker
        FechadenacimientoDatePickerLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        PiederechoLabel                matlab.ui.control.Label
        PieizquierdoLabel              matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        GuardarButton                  matlab.ui.control.Button
        HapresentadoalgunalceravenosaanteriormenteDropDown  matlab.ui.control.DropDown
        HapresentadoalgunalceravenosaanteriormenteDropDownLabel  matlab.ui.control.Label
        HasidodiagnsticadopreviamentecondiabtesDropDown  matlab.ui.control.DropDown
        HasidodiagnsticadopreviamentecondiabtesDropDownLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        DropDown                       matlab.ui.control.DropDown
        DropDownLabel_4                matlab.ui.control.Label
        HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown  matlab.ui.control.DropDown
        HanotadocambiosenlaestructuradesuspiesdeformidadesDropDownLabel  matlab.ui.control.Label
        TieneantecedentesdevaricesenlaspiernasDropDown  matlab.ui.control.DropDown
        TieneantecedentesdevaricesenlaspiernasDropDownLabel  matlab.ui.control.Label
        HatenidolesionesenelpiequehayantardadoensanarDropDown  matlab.ui.control.DropDown
        HatenidolesionesenelpiequehayantardadoensanarDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezinfeccionesenlospiesDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezinfeccionesenlospiesDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezunaamputacinenelpieDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezunaamputacinenelpieDropDownLabel  matlab.ui.control.Label
        HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown  matlab.ui.control.DropDown
        DropDownLabel_3                matlab.ui.control.Label
        QutipodezapatosusaregularmenteDropDown  matlab.ui.control.DropDown
        QutipodezapatosusaregularmenteDropDownLabel  matlab.ui.control.Label
        AlasemanaconqufrecuenciarealizaejercicioDropDown  matlab.ui.control.DropDown
        AlasemanaconqufrecuenciarealizaejercicioDropDownLabel  matlab.ui.control.Label
        TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown  matlab.ui.control.DropDown
        DropDownLabel_2                matlab.ui.control.Label
        QutanseguidosecortalasuasdelospiesalmesDropDown  matlab.ui.control.DropDown
        QutanseguidosecortalasuasdelospiesalmesDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezlcerasenelpieDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezlcerasenelpieDropDownLabel  matlab.ui.control.Label
        RealizatrabajopesadoDropDown   matlab.ui.control.DropDown
        RealizatrabajopesadoDropDownLabel  matlab.ui.control.Label
        EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown  matlab.ui.control.DropDown
        DropDownLabel                  matlab.ui.control.Label
        EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown  matlab.ui.control.DropDown
        EstsiguiendoalgunadietaespecialDropDownLabel  matlab.ui.control.Label
        TieneantecedentesfamiliaresconpiediabticoDropDown  matlab.ui.control.DropDown
        TieneantecedentesfamiliaresconpiediabticoDropDownLabel  matlab.ui.control.Label
        ResultadoButton                matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        CuntosaosdeevolucintienesudiabetesDropDown  matlab.ui.control.DropDown
        CuntosaosdeevolucintienesudiabetesDropDownLabel  matlab.ui.control.Label
        QutipodediabetesDropDown       matlab.ui.control.DropDown
        QutipodediabetesDropDownLabel  matlab.ui.control.Label
        SexoButtonGroup                matlab.ui.container.ButtonGroup
        MasculinoButton                matlab.ui.control.RadioButton
        FemeninoButton                 matlab.ui.control.RadioButton
        DomicilioEditField             matlab.ui.control.EditField
        DomicilioEditFieldLabel        matlab.ui.control.Label
        EdadEditField                  matlab.ui.control.NumericEditField
        EdadEditFieldLabel             matlab.ui.control.Label
        NombredelpacienteEditField     matlab.ui.control.EditField
        NombredelpacienteEditFieldLabel  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
    end

    
    properties
    puntaje = 0; % Inicializar la propiedad con un valor predeterminado
    clasificacion1 = 0; %Puntaje pie derecho 
    clasificacion2 = 0; %puntaje pie izquierdo
    imgd; %Imagen de pie derecho procesada
    imgi; %Imagen de pie izquierdo procesada
    mensaje1 = ''; % Inicializar como cadena vacía
    mensaje2 = ''; % Inicializar como cadena vacía
    mensaje3= '';
    mensaje4 = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
         % Parámetros de conexión base de datos
                datasource = 'Conexiondatabase';
                conn = database(datasource, 'root', '');
            
                % Definicón de variables de tala1
                Nombre = string(app.NombredelpacienteEditField.Value);
                Fechanac = string(app.FechadenacimientoDatePicker.Value);
                Edad = app.EdadEditField.Value;
                Domicilio = string(app.DomicilioEditField.Value);
                Estadociv = string(app.EstadoCivilEditField.Value);
                Telefono = string(app.NmerotelefnicoEditField.Value);
                masc = app.MasculinoButton.Value;
                if masc==1
                    Sexo = "Masculino";
                else
                    Sexo = "Femenino";
                end
            
                % Datos tabla datospersonales
                data1 = table(Nombre, Fechanac, Edad, Domicilio, Estadociv, Telefono, Sexo, 'VariableNames', ...
                    {'Nombre','Fechanacimiento','Edad','Domicilio','Estadocivil', 'Numerotelefono','Sexo'});
            
                % Insertar datos en tabla datospersonales
                tablename = 'datospersonales';
                sqlwrite(conn, tablename, data1)

                % Obtener el último ID insertado en la tabla 'datospersonales'
                sqlQuery = 'SELECT MAX(ID_Paciente) AS last_id FROM datospersonales';
                result = fetch(conn, sqlQuery);
                lastID = result.last_id;
            
         % Obtener valores seleccionados en los drop down
         Pregunta1 = app.HasidodiagnsticadopreviamentecondiabtesDropDown.Value;
         Pregunta2 = app.QutipodediabetesDropDown.Value;
         Pregunta3 = app.CuntosaosdeevolucintienesudiabetesDropDown.Value;
         Pregunta4 = app.TieneantecedentesfamiliaresconpiediabticoDropDown.Value;
         Pregunta5 = app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown.Value;
         Pregunta6 = app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown.Value;
         Pregunta7 = app.RealizatrabajopesadoDropDown.Value;
         Pregunta8 = app.HatenidoalgunavezlcerasenelpieDropDown.Value;
         Pregunta9 = app.QutanseguidosecortalasuasdelospiesalmesDropDown.Value;
         Pregunta10 = app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown.Value;
         Pregunta11 = app.AlasemanaconqufrecuenciarealizaejercicioDropDown.Value;
         Pregunta12 = app.QutipodezapatosusaregularmenteDropDown.Value;
         Pregunta13 = app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown.Value;
         Pregunta14 = app.HatenidoalgunavezunaamputacinenelpieDropDown.Value;
         Pregunta15 = app.HatenidoalgunavezinfeccionesenlospiesDropDown.Value;
         Pregunta16 = app.HatenidolesionesenelpiequehayantardadoensanarDropDown.Value;
         Pregunta17 = app.TieneantecedentesdevaricesenlaspiernasDropDown.Value;
         Pregunta18 = app.HapresentadoalgunalceravenosaanteriormenteDropDown.Value;
         Pregunta19 = app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown.Value;
         Pregunta20 = app.DropDown.Value;
         

        % Asignar valor numérico a cada respuesta

    if strcmp(Pregunta1, 'Si')
        valor1 = 1;
    else
        valor1 = 0;
    end

    if strcmp(Pregunta2, 'Diabetes tipo2')
        valor2 = 2;
    elseif strcmp(Pregunta2, 'Diabetes tipo1')
        valor2 = 1;
    else
        valor2 = 0;
    end

    if strcmp(Pregunta3, 'Mas de 10 años')
        valor3 = 3;
    elseif strcmp(Pregunta3, 'Entre 5 a 10 años')
        valor3 = 2;
    elseif strcmp(Pregunta3, 'Menos de 5 años')
        valor3 = 1;
    else
        valor3 = 0;
    end

    if strcmp(Pregunta4, 'Con pie diabetico')
        valor4 = 2;
    elseif strcmp(Pregunta4, 'Solo con diabetes')
        valor4 = 1;
    else
        valor4 = 0;
    end

    if strcmp(Pregunta5, 'No')
        valor5 = 2;
    elseif strcmp(Pregunta5, 'Si')
        valor5 = 1;
    else
        valor5 = 0;
    end

    if strcmp(Pregunta6, 'No')
        valor6 = 2;
    elseif strcmp(Pregunta6, 'Si')
        valor6 = 1;
    else
        valor6 = 0;
    end

    if strcmp(Pregunta7, 'Seguido')
        valor7 = 2;
    elseif strcmp(Pregunta7, 'En ocasiones')
        valor7 = 1;
    else
        valor7 = 0;
    end

    if strcmp(Pregunta8, 'Si')
        valor8 = 1;
    else
        valor8 = 0;
    end

    if strcmp(Pregunta9, 'Nunca')
        valor9 = 2;
    elseif strcmp(Pregunta9, 'Una vez')
        valor9 = 1;
    else
        valor9 = 0;
    end

    if strcmp(Pregunta10, 'Cambios persistentes')
        valor10 = 2;
    elseif strcmp(Pregunta10, 'Cambios ocasionales')
        valor10 = 1;
    else
        valor10 = 0;
    end

    if strcmp(Pregunta11, 'Nunca')
        valor11 = 3;
    elseif strcmp(Pregunta11, '1 o 2 veces')
        valor11 = 2;
    elseif strcmp(Pregunta11, '3 veces')
        valor11 = 1;
    else
        valor11 = 0;
    end

    if strcmp(Pregunta12, 'Descalzo')
        valor12 = 4;
    elseif strcmp(Pregunta12, 'Sandalias')
        valor12 = 3;
    elseif strcmp(Pregunta12, 'Zapatos')
        valor12 = 2;
    else
        valor12 = 1;
    end

    if strcmp(Pregunta13, 'Persistentes o severos')
        valor13 = 2;
    elseif strcmp(Pregunta13, 'Leves y ocasionales')
        valor13 = 1;
    else
        valor13 = 0;
    end

    if strcmp(Pregunta14, 'Dos veces')
        valor14 = 2;
    elseif strcmp(Pregunta14, 'Una vez')
        valor14 = 1;
    else
        valor14 = 0;
    end

    if strcmp(Pregunta15, '3 veces o mas')
        valor15 = 2;
    elseif strcmp(Pregunta15, '1 o 2 veces')
        valor15 = 1;
    else
        valor15 = 0;
    end

    if strcmp(Pregunta16, '3 veces o mas')
        valor16 = 2;
    elseif strcmp(Pregunta16, '1 o 2 veces')
        valor16 = 1;
    else
        valor16 = 0;
    end

    if strcmp(Pregunta17, 'Crónicas o graves')
        valor17 = 2;
    elseif strcmp(Pregunta17, 'Ocasionales o leves')
        valor17 = 1;
    else
        valor17 = 0;
    end

    if strcmp(Pregunta18, '3 veces o mas')
        valor18 = 2;
    elseif strcmp(Pregunta18, '1 o 2 veces')
        valor18 = 1;
    else
        valor18 = 0;
    end

    if strcmp(Pregunta19, 'Cambios significativos')
        valor19 = 2;
    elseif strcmp(Pregunta19, 'Cambios leves')
        valor19 = 1;
    else
        valor19 = 0;
    end

    if strcmp(Pregunta20, 'Dos o mas factores')
        valor20 = 2;
    elseif strcmp(Pregunta20, 'Un factor')
        valor20 = 1;
    else
        valor20 = 0;
    end


    % Sumar valores
    puntaje_final = valor1 + valor2 + valor3 + valor4 + valor5 + valor6 + valor7 + valor8 + valor9 + valor10 + valor11 + valor12 + valor13 + valor14 + valor15 + valor16 + valor17 + valor18 + valor19 + valor20;
    app.puntaje = puntaje_final; % Actualizar el valor de la propiedad puntaje_final

    % Mostrar resultado en app.FinalTextArea
    app.TextArea.Value = ['Puntaje final: ' num2str(puntaje_final)];
    
    % Datos para tabla 'respuestasvalores'
    data2 = table(lastID, valor1, valor2, valor3, valor4, valor5, valor6, valor7, valor8, valor9, valor10, ...
    valor11, valor12, valor13, valor14, valor15, valor16, valor17, valor18, valor19, valor20, puntaje_final, ...
    'VariableNames', {'ID_Paciente', 'Respuesta1', 'Respuesta2', 'Respuesta3', 'Respuesta4', 'Respuesta5', 'Respuesta6', ...
    'Respuesta7', 'Respuesta8', 'Respuesta9', 'Respuesta10', 'Respuesta11', 'Respuesta12', 'Respuesta13', ...
    'Respuesta14', 'Respuesta15', 'Respuesta16', 'Respuesta17', 'Respuesta18', 'Respuesta19', 'Respuesta20', ...
    'Totalvalor'});


     % Insertar datos en tabla respuestavalores
     tablename2 = 'respuestasvalores';
     sqlwrite(conn, tablename2, data2)


     % Cerrando conexión
     close(conn);
            
     % Mostrar un mensaje de éxito
     msg = 'Datos guardados exitosamente en la base de datos.';
     uialert(app.UIFigure, msg, 'Guardado', 'Icon', 'success');


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             [file, path] = uigetfile({'*.bmp';'*.jpg';'*.png'}, 'Seleccione una imagen');
            if file ~= 0
                imagePath = fullfile(path, file);

                % Agregar la ruta del directorio de la red neuronal
                redNeuronalDirectorio = 'C:\Users\jimen\OneDrive\Documentos\MATLAB';
                addpath(redNeuronalDirectorio);

                % Cargar la red neuronal previamente entrenada desde el archivo
                load('netfot.mat');
                % Llama a la función procesarImagen para procesar la imagen
                imgProcesada = procesarImagen(imagePath);
                
                % Muestra la imagen procesada en el componente de imagen
                app.Image.ImageSource = imgProcesada;

                % Clasifica la imagen utilizando la red neuronal "netfot"
                etiquetaClasificacion = classify(netfot, imgProcesada);

                 % Convertir la etiqueta de clasificación a una cadena de caracteres
                etiquetaStr = char(etiquetaClasificacion);
                
                % Asignar valores numéricos a las clases de la red neuronal
                if strcmp(etiquetaStr, 'SanoPlanta') || strcmp(etiquetaStr, 'SanoEmpeine')
                    valorClasificacion = 0;
                elseif strcmp(etiquetaStr, 'DiabeticoPlanta') || strcmp(etiquetaStr, 'DiabeticoEmpeine')
                    valorClasificacion = 1;
                else
                    % Manejar casos imprevistos
                    valorClasificacion = -1;
                end

                % Obtener el valor de la encuesta (de 0 al 41)
                % Asignar un valor numérico correspondiente a las clases de la encuesta
                if app.puntaje >= 0 && app.puntaje <= 15
                    valorEncuestaNumerico = 0;
                elseif app.puntaje >= 16 && app.puntaje <= 35
                    valorEncuestaNumerico = 1;
                elseif app.puntaje >= 36 && app.puntaje <= 43
                    valorEncuestaNumerico = 2;
                else
                    % Manejar casos imprevistos
                    valorEncuestaNumerico = -1;
                end
                
                 % Realizar la sumatoria de los valores de clasificación y encuesta
                sumaTotal = valorClasificacion + valorEncuestaNumerico;
                app.clasificacion1 = sumaTotal;
                
 
                 % Reajusta la imagen final 
                 imgProcesada = imresize(imgProcesada, [320, 240]);
                 app.imgd = imgProcesada;
                 
                 % Guardar la imagen procesada en formato PNG con el mismo nombre
                [~, filename, ~] = fileparts(imagePath);
                newImagePath = fullfile(path, [filename, '.png']); % Nuevo nombre con extensión PNG
            
                imwrite(imgProcesada, newImagePath);
            end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
                % Mostrar un mensaje en función del resultado de la
                % sumatoria pie derecho
                if app.clasificacion1 == 0
                    app.mensaje1 = 'No se encontraron indicios de afectaciones relacionadas con el pie diabético.';
                    app.mensaje3 = ['Limpieza diaria:' newline ...
                        'Lava tus pies con agua tibia y jabón suave a diario. Asegúrate de secarlos completamente, especialmente entre los dedos, para prevenir la acumulación de humedad.' newline ...
                        'Corte de uñas:' newline ...
                        'Corta tus uñas rectas y niveladas para evitar que se encarnen, de preferencia una o dos veces al mes.' newline ...
                        'Actividad física:' newline ...
                        'Mantén un nivel saludable de actividad física para mejorar la circulación sanguínea en tus pies y prevenir problemas de pies fríos y adormecimiento.' newline ...
                        'Inspección regular:' newline ...
                        'Examinar tus pies diariamente para detectar cualquier cambio inusual, como cortes, rozaduras, enrojecimiento o inflamación.' newline ...
                        'Control médico:' newline ...
                        'Aunque no tengas diabetes, es aconsejable someterse a revisiones periódicas con un médico (cada 6 meses) para evaluar la salud general de tus pies y detectar cualquier problema en etapas tempranas.']; ...
                elseif app.clasificacion1 == 1
                    app.mensaje1 = 'Se han detectado factores que podrían indicar una posible predisposición al pie diabético. Se recomienda consultar a un médico para una evaluación más detallada.';
                    app.mensaje3 = ['Control de glucosa:' newline ...
                        'Mantén tus niveles de glucosa en sangre dentro del rango recomendado por tu médico. Un buen control de la diabetes reduce el riesgo de complicaciones en los pies.' newline ...
                        'Exámenes médicos regulares:' newline ...
                        'Programa citas médicas regulares con un profesional de la salud que pueda monitorear la salud de tus pies y proporcionar orientación específica para tu situación.' newline ...
                        'Corte de uñas adecuado:' newline ... 
                        'Corta tus uñas rectas y niveladas para evitar que se encarnen. Si tienes dificultades para cortar tus propias uñas, consulta con un podólogo.' newline ...
                        'Inspección visual diaria:' newline ... 
                        'Examina tus pies a diario en busca de cortes, ampollas, llagas, enrojecimiento o hinchazón. Si encuentras algo inusual, busca atención médica de inmediato.' newline ...
                        'Higiene y cuidado diario:' newline ...
                        'Lava tus pies a diario con agua tibia y jabón suave. Sécalos completamente, especialmente entre los dedos. Aplica una crema hidratante suave en la piel seca, pero evita aplicarla entre los dedos.' newline ...
                        'Mantén la circulación sanguínea:' newline ...
                        'Realiza actividad física regular para mejorar la circulación en tus pies. Además, evita cruzar las piernas durante períodos prolongados y eleva las piernas cuando estés descansando.' newline ...
                        'Educación sobre pies diabéticos:' newline ...
                        'Infórmate sobre el pie diabético y sus síntomas para poder identificar cualquier problema en etapas tempranas. Aprende cómo cuidar tus pies de manera adecuada y cuándo buscar atención médica.'];
                elseif app.clasificacion1 >1
                    app.mensaje1 = 'Se han identificado síntomas o lesiones asociadas al pie diabético. Es importante acudir a un médico especialista para una evaluación profesional y un diagnóstico preciso.';
                    app.mensaje3 = ['Busca atención médica de inmediato:' newline ...
                        'Si tienes heridas, úlceras, dolor persistente, cambios bruscos de temperatura o cualquier otro síntoma inusual en tus pies, busca atención médica de inmediato. No esperes a que los síntomas empeoren.' newline ...
                        'Protege las heridas:' newline ...
                        'Cubre las heridas o úlceras con vendajes limpios y secos. Cambia los vendajes según las indicaciones de tu médico, esto ayuda a prevenir infecciones y promueve la cicatrización.' newline ...
                        'Evita la presión y el roce:' newline ...
                        'Usa calzado adecuado que proteja tus pies y evita puntos de presión. Evita usar zapatos apretados o que rocen en áreas sensibles.' newline ...
                        'No camines descalzo:' newline ...
                        'Mantén tus pies protegidos en todo momento, incluso en interiores. Caminar descalzo aumenta el riesgo de lesiones y complicaciones.' newline ...
                        'Controla la glucosa:' newline ...
                        'Mantén un control riguroso de tus niveles de glucosa en sangre. Un buen control ayuda a mejorar la circulación y favorece la cicatrización de heridas.' newline ...
                        'Sigue las recomendaciones médicas:' newline ...
                        'Sigue las indicaciones y tratamientos recomendados por tu médico.' newline ...
                        'Aprende más sobre el manejo del pie diabético y cómo prevenir complicaciones.' newline ...
                        'La educación te ayudará a reconocer los signos de advertencia y tomar medidas tempranas. Recuerda que el pie diabético es una complicación seria que requiere atención médica experta. Siempre busca la orientación de un profesional de la salud para tratar cualquier síntoma o problema relacionado con tus pies.'];

                else
                    % Manejar casos imprevistos
                    app.mensaje1 = 'Error: No se pudo determinar el resultado con certeza. Se recomienda consultar a un profesional de la salud para una evaluación adecuada.';
                end
                
                % Mostrar un mensaje en función del resultado de la
                % sumatoria pie izquierdo
                if app.clasificacion2 == 0
                    app.mensaje2 = 'No se encontraron indicios de afectaciones relacionadas con el pie diabético.';
                    app.mensaje4 = ['Limpieza diaria:' newline ...
                        'Lava tus pies con agua tibia y jabón suave a diario. Asegúrate de secarlos completamente, especialmente entre los dedos, para prevenir la acumulación de humedad.' newline ...
                        'Corte de uñas:' newline ...
                        'Corta tus uñas rectas y niveladas para evitar que se encarnen, de preferencia una o dos veces al mes.' newline ...
                        'Actividad física:' newline ...
                        'Mantén un nivel saludable de actividad física para mejorar la circulación sanguínea en tus pies y prevenir problemas de pies fríos y adormecimiento.' newline ...
                        'Inspección regular:' newline ...
                        'Examinar tus pies diariamente para detectar cualquier cambio inusual, como cortes, rozaduras, enrojecimiento o inflamación.' newline ...
                        'Control médico:' newline ...
                        'Aunque no tengas diabetes, es aconsejable someterse a revisiones periódicas con un médico (cada 6 meses) para evaluar la salud general de tus pies y detectar cualquier problema en etapas tempranas.']; ...
                elseif app.clasificacion2 == 1
                    app.mensaje2 = 'Se han detectado factores que podrían indicar una posible predisposición al pie diabético. Se recomienda consultar a un médico para una evaluación más detallada.';
                    app.mensaje4 =['Control de glucosa:' newline ...
                        'Mantén tus niveles de glucosa en sangre dentro del rango recomendado por tu médico. Un buen control de la diabetes reduce el riesgo de complicaciones en los pies.' newline ...
                        'Exámenes médicos regulares:' newline ...
                        'Programa citas médicas regulares con un profesional de la salud que pueda monitorear la salud de tus pies y proporcionar orientación específica para tu situación.' newline ...
                        'Corte de uñas adecuado:' newline ... 
                        'Corta tus uñas rectas y niveladas para evitar que se encarnen. Si tienes dificultades para cortar tus propias uñas, consulta con un podólogo.' newline ...
                        'Inspección visual diaria:' newline ... 
                        'Examina tus pies a diario en busca de cortes, ampollas, llagas, enrojecimiento o hinchazón. Si encuentras algo inusual, busca atención médica de inmediato.' newline ...
                        'Higiene y cuidado diario:' newline ...
                        'Lava tus pies a diario con agua tibia y jabón suave. Sécalos completamente, especialmente entre los dedos. Aplica una crema hidratante suave en la piel seca, pero evita aplicarla entre los dedos.' newline ...
                        'Mantén la circulación sanguínea:' newline ...
                        'Realiza actividad física regular para mejorar la circulación en tus pies. Además, evita cruzar las piernas durante períodos prolongados y eleva las piernas cuando estés descansando.' newline ...
                        'Educación sobre pies diabéticos:' newline ...
                        'Infórmate sobre el pie diabético y sus síntomas para poder identificar cualquier problema en etapas tempranas. Aprende cómo cuidar tus pies de manera adecuada y cuándo buscar atención médica.'];
                elseif app.clasificacion2 >1
                    app.mensaje2 = 'Se han identificado síntomas o lesiones asociadas al pie diabético. Es importante acudir a un médico especialista para una evaluación profesional y un diagnóstico preciso.';
                    app.mensaje4 = ['Busca atención médica de inmediato:' newline ...
                        'Si tienes heridas, úlceras, dolor persistente, cambios bruscos de temperatura o cualquier otro síntoma inusual en tus pies, busca atención médica de inmediato. No esperes a que los síntomas empeoren.' newline ...
                        'Protege las heridas:' newline ...
                        'Cubre las heridas o úlceras con vendajes limpios y secos. Cambia los vendajes según las indicaciones de tu médico, esto ayuda a prevenir infecciones y promueve la cicatrización.' newline ...
                        'Evita la presión y el roce:' newline ...
                        'Usa calzado adecuado que proteja tus pies y evita puntos de presión. Evita usar zapatos apretados o que rocen en áreas sensibles.' newline ...
                        'No camines descalzo:' newline ...
                        'Mantén tus pies protegidos en todo momento, incluso en interiores. Caminar descalzo aumenta el riesgo de lesiones y complicaciones.' newline ...
                        'Controla la glucosa:' newline ...
                        'Mantén un control riguroso de tus niveles de glucosa en sangre. Un buen control ayuda a mejorar la circulación y favorece la cicatrización de heridas.' newline ...
                        'Sigue las recomendaciones médicas:' newline ...
                        'Sigue las indicaciones y tratamientos recomendados por tu médico.' newline ...
                        'Aprende más sobre el manejo del pie diabético y cómo prevenir complicaciones.' newline ...
                        'La educación te ayudará a reconocer los signos de advertencia y tomar medidas tempranas. Recuerda que el pie diabético es una complicación seria que requiere atención médica experta. Siempre busca la orientación de un profesional de la salud para tratar cualquier síntoma o problema relacionado con tus pies.'];
                else
                    % Manejar casos imprevistos
                    app.mensaje2 = 'Error: No se pudo determinar el resultado con certeza. Se recomienda consultar a un profesional de la salud para una evaluación adecuada.';
                end
                
            % Restablecer valores
                 app.HasidodiagnsticadopreviamentecondiabtesDropDown.Value = app.HasidodiagnsticadopreviamentecondiabtesDropDown.Items{1};
                 app.QutipodediabetesDropDown.Value = app.QutipodediabetesDropDown.Items{1};
                 app.CuntosaosdeevolucintienesudiabetesDropDown.Value = app.CuntosaosdeevolucintienesudiabetesDropDown.Items{1};
                 app.TieneantecedentesfamiliaresconpiediabticoDropDown.Value = app.TieneantecedentesfamiliaresconpiediabticoDropDown.Items{1};
                 app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown.Value = app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown.Items{1};
                 app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown.Value = app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown.Items{1};
                 app.RealizatrabajopesadoDropDown.Value = app.RealizatrabajopesadoDropDown.Items{1};
                 app.HatenidoalgunavezlcerasenelpieDropDown.Value = app.HatenidoalgunavezlcerasenelpieDropDown.Items{1};
                 app.QutanseguidosecortalasuasdelospiesalmesDropDown.Value = app.QutanseguidosecortalasuasdelospiesalmesDropDown.Items{1};
                 app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown.Value = app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown.Items{1};
                 app.AlasemanaconqufrecuenciarealizaejercicioDropDown.Value = app.AlasemanaconqufrecuenciarealizaejercicioDropDown.Items{1};
                 app.QutipodezapatosusaregularmenteDropDown.Value = app.QutipodezapatosusaregularmenteDropDown.Items{1};
                 app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown.Value = app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown.Items{1};
                 app.HatenidoalgunavezunaamputacinenelpieDropDown.Value = app.HatenidoalgunavezunaamputacinenelpieDropDown.Items{1};
                 app.HatenidoalgunavezinfeccionesenlospiesDropDown.Value = app.HatenidoalgunavezinfeccionesenlospiesDropDown.Items{1};
                 app.HatenidolesionesenelpiequehayantardadoensanarDropDown.Value = app.HatenidolesionesenelpiequehayantardadoensanarDropDown.Items{1};
                 app.TieneantecedentesdevaricesenlaspiernasDropDown.Value = app.TieneantecedentesdevaricesenlaspiernasDropDown.Items{1};
                 app.HapresentadoalgunalceravenosaanteriormenteDropDown.Value = app.HapresentadoalgunalceravenosaanteriormenteDropDown.Items{1};
                 app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown.Value = app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown.Items{1};
                 app.DropDown.Value = app.DropDown.Items{1};

                 imageData = imread('C:\Users\jimen\OneDrive\Documentos\MATLAB\Ima.png');
                
%                 % Eliminar la imagen del componente de imagen
                 app.Image.ImageSource = imageData; % Esto establece la imagen a un valor vacío
                 app.Image2.ImageSource = imageData;
%                 
                %Limpiar cuadro de texto
                app.TextArea.Value = '';
                
                app.NombredelpacienteEditField.Value = '';
                app.DomicilioEditField.Value = '';
                app.EdadEditField.Value = 0;
                app.NmerotelefnicoEditField.Value = '';
                app.EstadoCivilEditField.Value = '';
                
                % Establecer una fecha predeterminada (por ejemplo, 1 de enero de 2023)
                fechaPredeterminada = datetime(2023, 1, 1);
                % Asignar la fecha predeterminada a la propiedad Value del DatePicker
                app.FechadenacimientoDatePicker.Value = fechaPredeterminada;
      
                resultado(app.mensaje1,app.mensaje2,app.mensaje3,app.mensaje4,app.imgd,app.imgi);

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.bmp';'*.jpg';'*.png'}, 'Seleccione una imagen');
            if file ~= 0
                imagePath = fullfile(path, file);

                % Agregar la ruta del directorio de la red neuronal
                redNeuronalDirectorio = 'C:\Users\jimen\OneDrive\Documentos\MATLAB';
                addpath(redNeuronalDirectorio);

                % Cargar la red neuronal previamente entrenada desde el archivo
                load('netfot.mat');
                % Llama a la función procesarImagen para procesar la imagen
                imgProcesada = procesarImagenI(imagePath);
                
                % Muestra la imagen procesada en el componente de imagen
                app.Image2.ImageSource = imgProcesada;

                % Clasifica la imagen utilizando la red neuronal "netfot"
                etiquetaClasificacion = classify(netfot, imgProcesada);

                 % Convertir la etiqueta de clasificación a una cadena de caracteres
                etiquetaStr = char(etiquetaClasificacion);
                
                % Asignar valores numéricos a las clases de la red neuronal
                if strcmp(etiquetaStr, 'SanoPlanta') || strcmp(etiquetaStr, 'SanoEmpeine')
                    valorClasificacion = 0;
                elseif strcmp(etiquetaStr, 'DiabeticoPlanta') || strcmp(etiquetaStr, 'DiabeticoEmpeine')
                    valorClasificacion = 1;
                else
                    % Manejar casos imprevistos
                    valorClasificacion = -1;
                end

                % Obtener el valor de la encuesta (de 0 al 41)
                % Asignar un valor numérico correspondiente a las clases de la encuesta
                if app.puntaje >= 0 && app.puntaje <= 15
                    valorEncuestaNumerico = 0;
                elseif app.puntaje >= 16 && app.puntaje <= 35
                    valorEncuestaNumerico = 1;
                elseif app.puntaje >= 36 && app.puntaje <= 42
                    valorEncuestaNumerico = 2;
                else
                    % Manejar casos imprevistos
                    valorEncuestaNumerico = -1;
                end
                
                 % Realizar la sumatoria de los valores de clasificación y encuesta
                sumaTotal = valorClasificacion + valorEncuestaNumerico;
                app.clasificacion2 = sumaTotal;
                
                 % Reajusta la imagen final 
                 imgProcesada = imresize(imgProcesada, [320, 240]);
                 app.imgi = imgProcesada;
                 
                 % Guardar la imagen procesada en formato PNG con el mismo nombre
                [~, filename, ~] = fileparts(imagePath);
                newImagePath = fullfile(path, [filename, '.png']); % Nuevo nombre con extensión PNG
            
                imwrite(imgProcesada, newImagePath);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            manualusuario
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'predipied.png';
            app.NombredelpacienteEditFieldLabel = uilabel(app.UIFigure);
            app.NombredelpacienteEditFieldLabel.Text = 'Nombre del paciente';
            app.NombredelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.DomicilioEditFieldLabel = uilabel(app.UIFigure);
            app.DomicilioEditFieldLabel.Text = 'Domicilio';
            app.DomicilioEditField = uieditfield(app.UIFigure, 'text');
            app.SexoButtonGroup = uibuttongroup(app.UIFigure);
            app.SexoButtonGroup.Title = 'Sexo';
            app.FemeninoButton = uiradiobutton(app.SexoButtonGroup);
            app.FemeninoButton.Text = 'Femenino';
            app.FemeninoButton.Value = true;
            app.MasculinoButton = uiradiobutton(app.SexoButtonGroup);
            app.MasculinoButton.Text = 'Masculino';
            app.QutipodediabetesDropDownLabel = uilabel(app.UIFigure);
            app.QutipodediabetesDropDownLabel.Text = '¿Qué tipo de diabetes?';
            app.QutipodediabetesDropDown = uidropdown(app.UIFigure);
            app.QutipodediabetesDropDown.Items = {'No tengo diabetes', 'Diabetes tipo1', 'Diabetes tipo2'};
            app.QutipodediabetesDropDown.Value = 'No tengo diabetes';
            app.CuntosaosdeevolucintienesudiabetesDropDownLabel = uilabel(app.UIFigure);
            app.CuntosaosdeevolucintienesudiabetesDropDownLabel.Text = '¿Cuántos años de evolución tiene su diabetes?';
            app.CuntosaosdeevolucintienesudiabetesDropDown = uidropdown(app.UIFigure);
            app.CuntosaosdeevolucintienesudiabetesDropDown.Items = {'No tengo diabetes', 'Menos de 5 años', 'Entre 5 a 10 años', 'Mas de 10 años'};
            app.CuntosaosdeevolucintienesudiabetesDropDown.Value = 'No tengo diabetes';
            app.TextArea = uitextarea(app.UIFigure);
            app.ResultadoButton = uibutton(app.UIFigure, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Text = 'Resultado';
            app.TieneantecedentesfamiliaresconpiediabticoDropDownLabel = uilabel(app.UIFigure);
            app.TieneantecedentesfamiliaresconpiediabticoDropDownLabel.Text = '¿Tiene antecedentes familiares con pie diabético?';
            app.TieneantecedentesfamiliaresconpiediabticoDropDown = uidropdown(app.UIFigure);
            app.TieneantecedentesfamiliaresconpiediabticoDropDown.Items = {'No', 'Solo con diabetes', 'Con pie diabetico'};
            app.TieneantecedentesfamiliaresconpiediabticoDropDown.Value = 'No';
            app.EstsiguiendoalgunadietaespecialDropDownLabel = uilabel(app.UIFigure);
            app.EstsiguiendoalgunadietaespecialDropDownLabel.Text = '¿Está siguiendo algun plan de alimentación adecuado a su enfermedad?';
            app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown = uidropdown(app.UIFigure);
            app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown.Items = {'No tengo diabetes', 'Si', 'No'};
            app.EstsiguiendoalgunplandealimentacinadecuadoasuenfermedadDropDown.Value = 'No tengo diabetes';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '¿Está aplicando insulina y/o otro medicamente para control de diabetes?';
            app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown = uidropdown(app.UIFigure);
            app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown.Items = {'No soy diabetico', 'Si', 'No'};
            app.EsttomandoinsulinauotromedicamenteparacontroldediabetesDropDown.Value = 'No soy diabetico';
            app.RealizatrabajopesadoDropDownLabel = uilabel(app.UIFigure);
            app.RealizatrabajopesadoDropDownLabel.Text = '¿Realiza trabajo pesado?';
            app.RealizatrabajopesadoDropDown = uidropdown(app.UIFigure);
            app.RealizatrabajopesadoDropDown.Items = {'No', 'En ocasiones', 'Seguido'};
            app.RealizatrabajopesadoDropDown.Value = 'No';
            app.HatenidoalgunavezlcerasenelpieDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezlcerasenelpieDropDownLabel.Text = '¿Ha tenido alguna vez úlceras en el pie?';
            app.HatenidoalgunavezlcerasenelpieDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezlcerasenelpieDropDown.Items = {'No', 'Si'};
            app.HatenidoalgunavezlcerasenelpieDropDown.Value = 'No';
            app.QutanseguidosecortalasuasdelospiesalmesDropDownLabel = uilabel(app.UIFigure);
            app.QutanseguidosecortalasuasdelospiesalmesDropDownLabel.Text = '¿Qué tan seguido se corta las uñas de los pies al mes?';
            app.QutanseguidosecortalasuasdelospiesalmesDropDown = uidropdown(app.UIFigure);
            app.QutanseguidosecortalasuasdelospiesalmesDropDown.Items = {'2 veces o mas', 'Una vez', 'Nunca'};
            app.QutanseguidosecortalasuasdelospiesalmesDropDown.Value = '2 veces o mas';
            app.DropDownLabel_2 = uilabel(app.UIFigure);
            app.DropDownLabel_2.Text = '¿Tiene cambios de temperatura de los pies como calor o frío excesivo?';
            app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown = uidropdown(app.UIFigure);
            app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown.Items = {'No', 'Cambios ocasionales', 'Cambios persistentes'};
            app.TienecambiosdetemperaturadelospiescomocalorofroexcesivoDropDown.Value = 'No';
            app.AlasemanaconqufrecuenciarealizaejercicioDropDownLabel = uilabel(app.UIFigure);
            app.AlasemanaconqufrecuenciarealizaejercicioDropDownLabel.Text = '¿A la semana, con qué frecuencia realiza ejercicio?';
            app.AlasemanaconqufrecuenciarealizaejercicioDropDown = uidropdown(app.UIFigure);
            app.AlasemanaconqufrecuenciarealizaejercicioDropDown.Items = {'4 veces o mas', '3 veces', '1 o 2 veces', 'Nunca'};
            app.AlasemanaconqufrecuenciarealizaejercicioDropDown.Value = '4 veces o mas';
            app.QutipodezapatosusaregularmenteDropDownLabel = uilabel(app.UIFigure);
            app.QutipodezapatosusaregularmenteDropDownLabel.Text = '¿Qué tipo de zapatos usa regularmente?';
            app.QutipodezapatosusaregularmenteDropDown = uidropdown(app.UIFigure);
            app.QutipodezapatosusaregularmenteDropDown.Items = {'Tennis', 'Zapatos cerrados', 'Sandalias', 'Descalzo'};
            app.QutipodezapatosusaregularmenteDropDown.Value = 'Tennis';
            app.DropDownLabel_3 = uilabel(app.UIFigure);
            app.DropDownLabel_3.Text = '¿Ha tenido cambios en la sensibilidad o circulación de sus pies?';
            app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown = uidropdown(app.UIFigure);
            app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown.Items = {'No', 'Ocasionales o leves', 'Persistentes o severos'};
            app.HatenidocambiosenlasensibilidadocirculacindesuspiesDropDown.Value = 'No';
            app.HatenidoalgunavezunaamputacinenelpieDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunaamputacinenelpieDropDownLabel.Text = '¿Ha tenido alguna vez una amputación en el pie?';
            app.HatenidoalgunavezunaamputacinenelpieDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezunaamputacinenelpieDropDown.Items = {'Nunca', 'Una vez', 'Dos veces'};
            app.HatenidoalgunavezunaamputacinenelpieDropDown.Value = 'Nunca';
            app.HatenidoalgunavezinfeccionesenlospiesDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezinfeccionesenlospiesDropDownLabel.Text = '¿Ha tenido alguna vez infecciones en los pies?';
            app.HatenidoalgunavezinfeccionesenlospiesDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezinfeccionesenlospiesDropDown.Items = {'Nunca', '1 o 2 veces', '3 veces o mas'};
            app.HatenidoalgunavezinfeccionesenlospiesDropDown.Value = 'Nunca';
            app.HatenidolesionesenelpiequehayantardadoensanarDropDownLabel = uilabel(app.UIFigure);
            app.HatenidolesionesenelpiequehayantardadoensanarDropDownLabel.Text = '¿Ha tenido lesiones en el pie que hayan tardado en sanar?';
            app.HatenidolesionesenelpiequehayantardadoensanarDropDown = uidropdown(app.UIFigure);
            app.HatenidolesionesenelpiequehayantardadoensanarDropDown.Items = {'Nunca', '1 o 2 veces', '3 veces o mas'};
            app.HatenidolesionesenelpiequehayantardadoensanarDropDown.Value = 'Nunca';
            app.TieneantecedentesdevaricesenlaspiernasDropDownLabel = uilabel(app.UIFigure);
            app.TieneantecedentesdevaricesenlaspiernasDropDownLabel.Text = '¿Tiene antecedentes de enfermedades vasculares periféricas como varices en las piernas?';
            app.TieneantecedentesdevaricesenlaspiernasDropDown = uidropdown(app.UIFigure);
            app.TieneantecedentesdevaricesenlaspiernasDropDown.Items = {'No', 'Ocasionales o leves', 'Crónicas o graves'};
            app.TieneantecedentesdevaricesenlaspiernasDropDown.Value = 'No';
            app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDownLabel = uilabel(app.UIFigure);
            app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDownLabel.Text = '¿Ha notado cambios en la estructura de sus pies (deformidades)?';
            app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown = uidropdown(app.UIFigure);
            app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown.Items = {'No', 'Cambios leves', 'Cambios significativos'};
            app.HanotadocambiosenlaestructuradesuspiesdeformidadesDropDown.Value = 'No';
            app.DropDownLabel_4 = uilabel(app.UIFigure);
            app.DropDownLabel_4.Text = '¿Tiene otros factores de riesgo como obesidad, hipertensión arterial, dislipidemia, etc.?';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Ninguno', 'Un factor', 'Dos o mas factores'};
            app.DropDown.Value = 'Ninguno';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Ima.png';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'icons8-foot-100.png';
            app.Button.Text = '';
            app.HasidodiagnsticadopreviamentecondiabtesDropDownLabel = uilabel(app.UIFigure);
            app.HasidodiagnsticadopreviamentecondiabtesDropDownLabel.Text = '¿Ha sido diagnósticado previamente con diabétes?';
            app.HasidodiagnsticadopreviamentecondiabtesDropDown = uidropdown(app.UIFigure);
            app.HasidodiagnsticadopreviamentecondiabtesDropDown.Items = {'No ', 'Si'};
            app.HasidodiagnsticadopreviamentecondiabtesDropDown.Value = 'No ';
            app.HapresentadoalgunalceravenosaanteriormenteDropDownLabel = uilabel(app.UIFigure);
            app.HapresentadoalgunalceravenosaanteriormenteDropDownLabel.Text = '¿Ha presentado alguna úlcera venosa anteriormente?';
            app.HapresentadoalgunalceravenosaanteriormenteDropDown = uidropdown(app.UIFigure);
            app.HapresentadoalgunalceravenosaanteriormenteDropDown.Items = {'Nunca', '1 o 2 veces', '3 veces o mas'};
            app.HapresentadoalgunalceravenosaanteriormenteDropDown.Value = 'Nunca';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'izq100.png';
            app.Button_2.Text = '';
            app.PieizquierdoLabel = uilabel(app.UIFigure);
            app.PieizquierdoLabel.Text = 'Pie izquierdo';
            app.PiederechoLabel = uilabel(app.UIFigure);
            app.PiederechoLabel.Text = 'Pie derecho';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Ima.png';
            app.FechadenacimientoDatePickerLabel = uilabel(app.UIFigure);
            app.FechadenacimientoDatePickerLabel.Text = 'Fecha de nacimiento';
            app.FechadenacimientoDatePicker = uidatepicker(app.UIFigure);
            app.EstadoCivilEditFieldLabel = uilabel(app.UIFigure);
            app.EstadoCivilEditFieldLabel.Text = 'Estado Civil';
            app.EstadoCivilEditField = uieditfield(app.UIFigure, 'text');
            app.NmerotelefnicoEditFieldLabel = uilabel(app.UIFigure);
            app.NmerotelefnicoEditFieldLabel.Text = 'Número telefónico';
            app.NmerotelefnicoEditField = uieditfield(app.UIFigure, 'text');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'flechaizq.png';
            app.Button_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encuesta2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end