classdef Encuestapiediabetico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SexoButtonGroup               matlab.ui.container.ButtonGroup
        FemeninoButton                matlab.ui.control.RadioButton
        MasculinoButton               matlab.ui.control.RadioButton
        EstatomandoinsulinauDropDown  matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel  matlab.ui.control.Label
        CiudadEditField_2             matlab.ui.control.EditField
        CiudadEditField_2Label        matlab.ui.control.Label
        EstasiguiendoalgunadietaespecialDropDown  matlab.ui.control.DropDown
        EstasiguiendoalgunadietaespecialDropDownLabel  matlab.ui.control.Label
        EdadEditField                 matlab.ui.control.NumericEditField
        EdadEditFieldLabel            matlab.ui.control.Label
        NombredelpacienteEditField    matlab.ui.control.EditField
        NombredelpacienteEditFieldLabel  matlab.ui.control.Label
        ResultadoButton               matlab.ui.control.Button
        FinalTextArea                 matlab.ui.control.TextArea
        FinalTextAreaLabel            matlab.ui.control.Label
        HatenidoalgunavezunalesinenlospiesDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezunalesinenlospiesDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezunainfeccinenlospiesDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezunainfeccinenlospiesDropDownLabel  matlab.ui.control.Label
        QutipodezapatosusaregularmenteDropDown  matlab.ui.control.DropDown
        QutipodezapatosusaregularmenteDropDownLabel  matlab.ui.control.Label
        AlasemanaconquefrecuenciahacerejercicioDropDown  matlab.ui.control.DropDown
        AlasemanaconquefrecuenciahacerejercicioDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezunaamputacindepieodedodelpieDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel  matlab.ui.control.Label
        QuetanseguidosecortalasuasdelospiesalaquincenaDropDown  matlab.ui.control.DropDown
        QuetanseguidosecortalasuasdelospiesalaquincenaDropDownLabel  matlab.ui.control.Label
        HatenidoalgunavezunalceraenelpieDropDown  matlab.ui.control.DropDown
        HatenidoalgunavezunalceraenelpieLabel  matlab.ui.control.Label
        RealizatrabajopesadoDropDown  matlab.ui.control.DropDown
        RealizatrabajopesadoDropDownLabel  matlab.ui.control.Label
        TienediabetesDropDown         matlab.ui.control.DropDown
        TienediabetesDropDownLabel    matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)

   % Obtener valores seleccionados en los drop down
    diabetes = app.TienediabetesDropDown.Value;
    trabajo_pesado = app.RealizatrabajopesadoDropDown.Value;
    
    % Asignar valor numérico a cada respuesta
    if strcmp(diabetes, 'Si')
        valor = 2;
    elseif strcmp(diabetes, 'No')
        valor = 0;
    else
        valor = 1;
    end
    
    if strcmp(trabajo_pesado, 'Seguido ')
        valor2 = 2;
    elseif strcmp(trabajo_pesado, 'De vez en cuando')
        valor2 = 1;
    else
        valor2 = 0;
    end
    
    % Sumar valores
    puntaje_final = valor + valor2;
    
    % Mostrar resultado en app.FinalTextArea
    app.FinalTextArea.Value = ['Puntaje final: ' num2str(puntaje_final)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TienediabetesDropDownLabel = uilabel(app.UIFigure);
            app.TienediabetesDropDownLabel.Text = '¿Tiene diabetes?';
            app.TienediabetesDropDown = uidropdown(app.UIFigure);
            app.TienediabetesDropDown.Items = {'Si', 'No', 'No se'};
            app.TienediabetesDropDown.Value = 'Si';
            app.RealizatrabajopesadoDropDownLabel = uilabel(app.UIFigure);
            app.RealizatrabajopesadoDropDownLabel.Text = '¿Realiza trabajo pesado?';
            app.RealizatrabajopesadoDropDown = uidropdown(app.UIFigure);
            app.RealizatrabajopesadoDropDown.Items = {'Seguido ', 'De vez en cuando', 'Nunca'};
            app.RealizatrabajopesadoDropDown.Value = 'Seguido ';
            app.HatenidoalgunavezunalceraenelpieLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunalceraenelpieLabel.Text = {'¿Ha tenido alguna vez una '; 'úlcera en el pie?'};
            app.HatenidoalgunavezunalceraenelpieDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezunalceraenelpieDropDown.Items = {'Si', 'No'};
            app.HatenidoalgunavezunalceraenelpieDropDown.Value = 'Si';
            app.QuetanseguidosecortalasuasdelospiesalaquincenaDropDownLabel = uilabel(app.UIFigure);
            app.QuetanseguidosecortalasuasdelospiesalaquincenaDropDownLabel.Text = {'¿Que tan seguido'; 'se corta las uñas de los pies'; 'a la quincena?'};
            app.QuetanseguidosecortalasuasdelospiesalaquincenaDropDown = uidropdown(app.UIFigure);
            app.QuetanseguidosecortalasuasdelospiesalaquincenaDropDown.Items = {'Regularmente', '2 o 3 ', '1 ', 'Nunca'};
            app.QuetanseguidosecortalasuasdelospiesalaquincenaDropDown.Value = 'Regularmente';
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel.Text = {'¿Ha tenido alguna vez una'; 'amputación de pie o'; 'dedo del pie?'};
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDown.Items = {'Si', 'No'};
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDown.Value = 'Si';
            app.AlasemanaconquefrecuenciahacerejercicioDropDownLabel = uilabel(app.UIFigure);
            app.AlasemanaconquefrecuenciahacerejercicioDropDownLabel.Text = {'¿A la semana  con que'; 'frecuencia hacer '; 'ejercicio?'};
            app.AlasemanaconquefrecuenciahacerejercicioDropDown = uidropdown(app.UIFigure);
            app.AlasemanaconquefrecuenciahacerejercicioDropDown.Items = {'Nunca', '1 o 2 veces', '3 o 4 veces', 'más de 4 '};
            app.AlasemanaconquefrecuenciahacerejercicioDropDown.Value = '1 o 2 veces';
            app.QutipodezapatosusaregularmenteDropDownLabel = uilabel(app.UIFigure);
            app.QutipodezapatosusaregularmenteDropDownLabel.Text = {'¿Qué tipo de zapatos'; 'usa regularmente?'};
            app.QutipodezapatosusaregularmenteDropDown = uidropdown(app.UIFigure);
            app.QutipodezapatosusaregularmenteDropDown.Items = {'De taco alto', 'De punta estrecha', 'Ajustados', 'Comodo ', 'No usa zapatos'};
            app.QutipodezapatosusaregularmenteDropDown.Value = 'De taco alto';
            app.HatenidoalgunavezunainfeccinenlospiesDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunainfeccinenlospiesDropDownLabel.Text = {'¿Ha tenido alguna vez una '; 'infección en los pies?'};
            app.HatenidoalgunavezunainfeccinenlospiesDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezunainfeccinenlospiesDropDown.Items = {'Nunca', '1 vez', '2 o 3 veces', '4 o más'};
            app.HatenidoalgunavezunainfeccinenlospiesDropDown.Value = 'Nunca';
            app.HatenidoalgunavezunalesinenlospiesDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunalesinenlospiesDropDownLabel.Text = {'¿Ha tenido alguna vez'; 'una lesión en los pies?'};
            app.HatenidoalgunavezunalesinenlospiesDropDown = uidropdown(app.UIFigure);
            app.HatenidoalgunavezunalesinenlospiesDropDown.Items = {'Nunca', '1 vez', '2 o 3 veces ', '4 o más'};
            app.HatenidoalgunavezunalesinenlospiesDropDown.Value = 'Nunca';
            app.FinalTextAreaLabel = uilabel(app.UIFigure);
            app.FinalTextAreaLabel.Text = 'Final';
            app.FinalTextArea = uitextarea(app.UIFigure);
            app.FinalTextArea.Placeholder = 'El valor es: ';
            app.ResultadoButton = uibutton(app.UIFigure, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Text = 'Resultado';
            app.NombredelpacienteEditFieldLabel = uilabel(app.UIFigure);
            app.NombredelpacienteEditFieldLabel.Text = 'Nombre del paciente';
            app.NombredelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.EstasiguiendoalgunadietaespecialDropDownLabel = uilabel(app.UIFigure);
            app.EstasiguiendoalgunadietaespecialDropDownLabel.Text = '¿Esta siguiendo alguna dieta especial?';
            app.EstasiguiendoalgunadietaespecialDropDown = uidropdown(app.UIFigure);
            app.EstasiguiendoalgunadietaespecialDropDown.Items = {'Si', 'No', 'Parcial'};
            app.EstasiguiendoalgunadietaespecialDropDown.Value = 'Si';
            app.CiudadEditField_2Label = uilabel(app.UIFigure);
            app.CiudadEditField_2Label.Text = 'Ciudad';
            app.CiudadEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel.Text = {'¿Esta tomando insulina u'; 'otro medicamento para '; 'control de diabetes?'};
            app.EstatomandoinsulinauDropDown = uidropdown(app.UIFigure);
            app.EstatomandoinsulinauDropDown.Items = {'Si', 'No', 'Parcialmente'};
            app.EstatomandoinsulinauDropDown.Value = 'Si';
            app.SexoButtonGroup = uibuttongroup(app.UIFigure);
            app.SexoButtonGroup.Title = 'Sexo';
            app.MasculinoButton = uiradiobutton(app.SexoButtonGroup);
            app.MasculinoButton.Text = 'Masculino';
            app.MasculinoButton.Value = true;
            app.FemeninoButton = uiradiobutton(app.SexoButtonGroup);
            app.FemeninoButton.Text = 'Femenino';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encuestapiediabetico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end