classdef Encuestapiediabetico17p < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CambiotemperaturapiesDropDown   matlab.ui.control.DropDown
        TienescambiosenlatemperaturadelospiescomocalorofroexcesivoLabel  matlab.ui.control.Label
        AntecedentesvascularperifericaDropDown_6  matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_6  matlab.ui.control.Label
        TienesantecedentesfamiliaresDropDown  matlab.ui.control.DropDown
        TienesantecedentesfamiliaresconpiediabticoDropDownLabel  matlab.ui.control.Label
        OtrosfactoresderiesgoDropDown_5  matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_5  matlab.ui.control.Label
        CambiodesensibilidadDropDown_4  matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_4  matlab.ui.control.Label
        TardanzaensanarDropDown_3       matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_3  matlab.ui.control.Label
        TiempocondiabetesDropDown       matlab.ui.control.DropDown
        CuntosaoshatenidodiabetesDropDownLabel  matlab.ui.control.Label
        DiagnosticodediabetesDropDown_2  matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_2  matlab.ui.control.Label
        SexoButtonGroup                 matlab.ui.container.ButtonGroup
        FemeninoButton                  matlab.ui.control.RadioButton
        MasculinoButton                 matlab.ui.control.RadioButton
        InsulinaocontrolDropDown        matlab.ui.control.DropDown
        EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel  matlab.ui.control.Label
        CiudadEditField_2               matlab.ui.control.EditField
        CiudadEditField_2Label          matlab.ui.control.Label
        TipodedietaDropDown             matlab.ui.control.DropDown
        EstasiguiendoalgunadietaespecialDropDownLabel  matlab.ui.control.Label
        EdadEditField                   matlab.ui.control.NumericEditField
        EdadEditFieldLabel              matlab.ui.control.Label
        NombredelpacienteEditField      matlab.ui.control.EditField
        NombredelpacienteEditFieldLabel  matlab.ui.control.Label
        ResultadoButton                 matlab.ui.control.Button
        FinalTextArea                   matlab.ui.control.TextArea
        FinalTextAreaLabel              matlab.ui.control.Label
        InfeccinenlospiesDropDown       matlab.ui.control.DropDown
        HatenidoalgunavezunainfeccinenlospiesDropDownLabel  matlab.ui.control.Label
        TipodezapatosDropDown           matlab.ui.control.DropDown
        QutipodezapatosusaregularmenteDropDownLabel  matlab.ui.control.Label
        FrecuenciadeejercicioDropDown   matlab.ui.control.DropDown
        AlasemanaconquefrecuenciahacerejercicioDropDownLabel  matlab.ui.control.Label
        AmputacionesenelpieDropDown     matlab.ui.control.DropDown
        HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel  matlab.ui.control.Label
        CuidadodeunasDropDown           matlab.ui.control.DropDown
        QuetanseguidosecortalasuasdelospiesalmesDropDownLabel  matlab.ui.control.Label
        AntecedentesulcerasDropDown     matlab.ui.control.DropDown
        HatenidoalgunavezunalceraenelpieLabel  matlab.ui.control.Label
        TrabajopesadoDropDown           matlab.ui.control.DropDown
        RealizatrabajopesadoDropDownLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)

   % Obtener valores seleccionados en los drop down
    diabetes = app.HasidodiagnosticadoDropDown.Value;
    trabajo_pesado = app.TrabajopesadoDropDown.Value;
    
    % Asignar valor numérico a cada respuesta
    if strcmp(diabetes, 'Si')
        valor = 2;
    elseif strcmp(diabetes, 'No')
        valor = 0;
    else
        valor = 1;
    end
    
    if strcmp(trabajo_pesado, 'Seguido ')
        valor2 = 2;
    elseif strcmp(trabajo_pesado, 'De vez en cuando')
        valor2 = 1;
    else
        valor2 = 0;
    end
    
    % Sumar valores
    puntaje_final = valor + valor2;
    
    % Mostrar resultado en app.FinalTextArea
    app.FinalTextArea.Value = ['Puntaje final: ' num2str(puntaje_final)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RealizatrabajopesadoDropDownLabel = uilabel(app.UIFigure);
            app.RealizatrabajopesadoDropDownLabel.Text = '¿Realiza trabajo pesado?';
            app.TrabajopesadoDropDown = uidropdown(app.UIFigure);
            app.TrabajopesadoDropDown.Items = {'Seguido ', 'De vez en cuando', 'Nunca'};
            app.TrabajopesadoDropDown.Value = 'Seguido ';
            app.HatenidoalgunavezunalceraenelpieLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunalceraenelpieLabel.Text = {'¿Ha tenido alguna vez una '; 'úlcera en el pie?'};
            app.AntecedentesulcerasDropDown = uidropdown(app.UIFigure);
            app.AntecedentesulcerasDropDown.Items = {'No', 'Si, úlceras previas'};
            app.AntecedentesulcerasDropDown.Value = 'No';
            app.QuetanseguidosecortalasuasdelospiesalmesDropDownLabel = uilabel(app.UIFigure);
            app.QuetanseguidosecortalasuasdelospiesalmesDropDownLabel.Text = {'¿Que tan seguido'; 'se corta las uñas de los pies'; 'al mes?'};
            app.CuidadodeunasDropDown = uidropdown(app.UIFigure);
            app.CuidadodeunasDropDown.Items = {'Nunca', '1 vez ', '2 o 3  veces'};
            app.CuidadodeunasDropDown.Value = 'Nunca';
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunaamputacindepieodedodelpieDropDownLabel.Text = {'¿Ha tenido alguna vez una'; 'amputación de pie o'; 'dedo del pie?'};
            app.AmputacionesenelpieDropDown = uidropdown(app.UIFigure);
            app.AmputacionesenelpieDropDown.Items = {'No', 'Si, una vez', 'Si, dos veces o más'};
            app.AmputacionesenelpieDropDown.Value = 'No';
            app.AlasemanaconquefrecuenciahacerejercicioDropDownLabel = uilabel(app.UIFigure);
            app.AlasemanaconquefrecuenciahacerejercicioDropDownLabel.Text = {'¿A la semana  con que'; 'frecuencia hacer '; 'ejercicio?'};
            app.FrecuenciadeejercicioDropDown = uidropdown(app.UIFigure);
            app.FrecuenciadeejercicioDropDown.Items = {'Nunca', '1 o 2 veces', '3 o 4 veces', 'más de 4 '};
            app.FrecuenciadeejercicioDropDown.Value = '1 o 2 veces';
            app.QutipodezapatosusaregularmenteDropDownLabel = uilabel(app.UIFigure);
            app.QutipodezapatosusaregularmenteDropDownLabel.Text = {'¿Qué tipo de zapatos'; 'usa regularmente?'};
            app.TipodezapatosDropDown = uidropdown(app.UIFigure);
            app.TipodezapatosDropDown.Items = {'De tacon alto', 'De punta estrecha', 'Ajustados', 'Cómodos ', 'Sandalias', 'Descalzo'};
            app.TipodezapatosDropDown.Value = 'De tacon alto';
            app.HatenidoalgunavezunainfeccinenlospiesDropDownLabel = uilabel(app.UIFigure);
            app.HatenidoalgunavezunainfeccinenlospiesDropDownLabel.Text = {'¿Ha tenido alguna vez una '; 'infección en los pies?'};
            app.InfeccinenlospiesDropDown = uidropdown(app.UIFigure);
            app.InfeccinenlospiesDropDown.Items = {'Nunca', '1 vez', '2 o 3 veces', '4 o más'};
            app.InfeccinenlospiesDropDown.Value = 'Nunca';
            app.FinalTextAreaLabel = uilabel(app.UIFigure);
            app.FinalTextAreaLabel.Text = 'Final';
            app.FinalTextArea = uitextarea(app.UIFigure);
            app.FinalTextArea.Placeholder = 'El valor es: ';
            app.ResultadoButton = uibutton(app.UIFigure, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Text = 'Resultado';
            app.NombredelpacienteEditFieldLabel = uilabel(app.UIFigure);
            app.NombredelpacienteEditFieldLabel.Text = 'Nombre del paciente';
            app.NombredelpacienteEditField = uieditfield(app.UIFigure, 'text');
            app.EdadEditFieldLabel = uilabel(app.UIFigure);
            app.EdadEditFieldLabel.Text = 'Edad';
            app.EdadEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdadEditField.Limits = [0 Inf];
            app.EstasiguiendoalgunadietaespecialDropDownLabel = uilabel(app.UIFigure);
            app.EstasiguiendoalgunadietaespecialDropDownLabel.Text = '¿Esta siguiendo alguna dieta especial?';
            app.TipodedietaDropDown = uidropdown(app.UIFigure);
            app.TipodedietaDropDown.Items = {'Si', 'No', 'Parcial', 'No tengo diabetes'};
            app.TipodedietaDropDown.Value = 'Si';
            app.CiudadEditField_2Label = uilabel(app.UIFigure);
            app.CiudadEditField_2Label.Text = 'Ciudad';
            app.CiudadEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel.Text = {'¿Esta tomando insulina u'; 'otro medicamento para '; 'control de diabetes?'};
            app.InsulinaocontrolDropDown = uidropdown(app.UIFigure);
            app.InsulinaocontrolDropDown.Items = {'No tengo diabetes', 'Si', 'No', 'Parcialmente'};
            app.InsulinaocontrolDropDown.Value = 'Si';
            app.SexoButtonGroup = uibuttongroup(app.UIFigure);
            app.SexoButtonGroup.Title = 'Sexo';
            app.MasculinoButton = uiradiobutton(app.SexoButtonGroup);
            app.MasculinoButton.Text = 'Masculino';
            app.MasculinoButton.Value = true;
            app.FemeninoButton = uiradiobutton(app.SexoButtonGroup);
            app.FemeninoButton.Text = 'Femenino';
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_2 = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_2.Text = {'¿Ha sido diagnosticado '; 'previamente con diabetes?'};
            app.DiagnosticodediabetesDropDown_2 = uidropdown(app.UIFigure);
            app.DiagnosticodediabetesDropDown_2.Items = {'No', 'Si: Diabetes Tipo 1', 'Si: Diabetes Tipo 2', 'Si: otro tipo de diabetes'};
            app.DiagnosticodediabetesDropDown_2.Value = 'No';
            app.CuntosaoshatenidodiabetesDropDownLabel = uilabel(app.UIFigure);
            app.CuntosaoshatenidodiabetesDropDownLabel.Text = {'¿Cuántos años'; 'ha tenido diabetes?'};
            app.TiempocondiabetesDropDown = uidropdown(app.UIFigure);
            app.TiempocondiabetesDropDown.Items = {'No tengo diabetes', 'Menos de 5 años', 'Entre 5 y 10 años', 'Más de 10 años'};
            app.TiempocondiabetesDropDown.Value = 'No tengo diabetes';
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_3 = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_3.Text = {'¿Haz tenido heridas,'; 'ulceras o lesiones en el pie'; 'que hayan tardado en sanar?'};
            app.TardanzaensanarDropDown_3 = uidropdown(app.UIFigure);
            app.TardanzaensanarDropDown_3.Items = {'No', 'Si: una vez', 'Si: dos veces', 'Si: muy recurrente'};
            app.TardanzaensanarDropDown_3.Value = 'No';
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_4 = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_4.Text = {'¿Haz tenido cambios en '; 'la sensibilidad o circulación de tus'; 'pies, como hormigueo, dolor, etc.?'};
            app.CambiodesensibilidadDropDown_4 = uidropdown(app.UIFigure);
            app.CambiodesensibilidadDropDown_4.Items = {'No', 'Si: leves y ocasionales', 'Si: persistentes o severos'};
            app.CambiodesensibilidadDropDown_4.Value = 'No';
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_5 = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_5.Text = {'¿Tienes otros factores de riesgo '; ' como obesidad, hipertensión '; 'arterial, colesterol alto?'};
            app.OtrosfactoresderiesgoDropDown_5 = uidropdown(app.UIFigure);
            app.OtrosfactoresderiesgoDropDown_5.Items = {'No', 'Si: 1 factor de riesgo mencionados', 'Si: 2 o más factores de riesgo mencionados'};
            app.OtrosfactoresderiesgoDropDown_5.Value = 'No';
            app.TienesantecedentesfamiliaresconpiediabticoDropDownLabel = uilabel(app.UIFigure);
            app.TienesantecedentesfamiliaresconpiediabticoDropDownLabel.Text = {'¿Tienes antecedentes familiares'; 'con pie diabético?'};
            app.TienesantecedentesfamiliaresDropDown = uidropdown(app.UIFigure);
            app.TienesantecedentesfamiliaresDropDown.Items = {'No', 'Si, familiares con diabetes '};
            app.TienesantecedentesfamiliaresDropDown.Value = 'No';
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_6 = uilabel(app.UIFigure);
            app.EstatomandoinsulinauotromedicamentoparacontroldediabetesLabel_6.Text = {'¿Tienes antecedentes de '; 'enfermedades vasculares periféricas'; 'como dolor o ulceras en las piernas?'};
            app.AntecedentesvascularperifericaDropDown_6 = uidropdown(app.UIFigure);
            app.AntecedentesvascularperifericaDropDown_6.Items = {'No', 'Si: Leves o ocasionales', 'Si: Crónicas o graves'};
            app.AntecedentesvascularperifericaDropDown_6.Value = 'No';
            app.TienescambiosenlatemperaturadelospiescomocalorofroexcesivoLabel = uilabel(app.UIFigure);
            app.TienescambiosenlatemperaturadelospiescomocalorofroexcesivoLabel.Text = {'¿Tienes cambios en'; 'la temperatura de los pies'; 'como calor o frío excesivo?'};
            app.CambiotemperaturapiesDropDown = uidropdown(app.UIFigure);
            app.CambiotemperaturapiesDropDown.Items = {'Nunca', 'Si: ocasionales', 'Si: persistentes'};
            app.CambiotemperaturapiesDropDown.Value = 'Nunca';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Encuestapiediabetico17p

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end